classdef gui_ow_lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TOPSISTab                   matlab.ui.container.Tab
        RankingTextAreaLabel        matlab.ui.control.Label
        RankingTextArea             matlab.ui.control.TextArea
        Run_TOPSISButton            matlab.ui.control.Button
        WprowadzwektorwagowyButton  matlab.ui.control.Button
        WprowadzograniczeniaButton  matlab.ui.control.Button
        WprowadzAButton             matlab.ui.control.Button
        WprowadzBButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        DanezplikuButton            matlab.ui.control.Button
        Point_typeDropDownLabel     matlab.ui.control.Label
        Point_typeDropDown          matlab.ui.control.DropDown
        WektorwagowyTextAreaLabel   matlab.ui.control.Label
        WektorwagowyTextArea        matlab.ui.control.TextArea
        OgraniczeniaTextAreaLabel   matlab.ui.control.Label
        OgraniczeniaTextArea        matlab.ui.control.TextArea
        ATextAreaLabel              matlab.ui.control.Label
        ATextArea                   matlab.ui.control.TextArea
        BTextAreaLabel              matlab.ui.control.Label
        BTextArea                   matlab.ui.control.TextArea
        DanewejcioweTextAreaLabel   matlab.ui.control.Label
        DanewejcioweTextArea        matlab.ui.control.TextArea
        MinMaxDropDownLabel         matlab.ui.control.Label
        MinMaxDropDown              matlab.ui.control.DropDown
        MetrykaDropDownLabel        matlab.ui.control.Label
        MetrykaDropDown             matlab.ui.control.DropDown
        SP_CSTab                    matlab.ui.container.Tab
        Run_SP_CSButton             matlab.ui.control.Button
        Ranking_2TextAreaLabel      matlab.ui.control.Label
        Ranking_2TextArea           matlab.ui.control.TextArea
        ImportujA0Button            matlab.ui.control.Button
        A0TextAreaLabel             matlab.ui.control.Label
        A0TextArea                  matlab.ui.control.TextArea
        ImportujA1Button            matlab.ui.control.Button
        A1TextAreaLabel             matlab.ui.control.Label
        A1TextArea                  matlab.ui.control.TextArea
        ImportujFuButton            matlab.ui.control.Button
        FuTextAreaLabel             matlab.ui.control.Label
        FuTextArea                  matlab.ui.control.TextArea
        UIAxes2                     matlab.ui.control.UIAxes
        SprawdzaniewarunkwniesprzecznociTab  matlab.ui.container.Tab
        ImportujA0Button_2          matlab.ui.control.Button
        A0TextArea_2Label           matlab.ui.control.Label
        A0TextArea_2                matlab.ui.control.TextArea
        ImportujA1Button_2          matlab.ui.control.Button
        A1TextArea_2Label           matlab.ui.control.Label
        A1TextArea_2                matlab.ui.control.TextArea
        ImportujA2Button            matlab.ui.control.Button
        ImportujA3Button            matlab.ui.control.Button
        A2TextAreaLabel             matlab.ui.control.Label
        A2TextArea                  matlab.ui.control.TextArea
        A3TextAreaLabel             matlab.ui.control.Label
        A3TextArea                  matlab.ui.control.TextArea
        UIAxes3                     matlab.ui.control.UIAxes
        RunButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
%         dane = [
%   700 2 6 3;
%   700 2 6 3;
%   250 2 4 5;
%   1200 6 9 8;
%   1200 6 9 8;
%   700 2 6 3;
%   450 3 8 9;
%   1500 2 3 10
% ];

dane = [
    700 2;
    700 2;
    250 2;
    1200 6;
    1200 6;
    700 2;
    450 3;
    1500 2
    ];

%         ograniczenia = [
%   0 0 0 0;
%   1600 7 10 20
% ];
ograniczenia = [
  -Inf -Inf;
  Inf Inf
];

%wektorWag = [0.45 0.3 0.15 0.1];
wektorWag = [1 1];
        minMax = [1 1]; 

        pointTypes = "ALL";
        %pointTypes = "NZD";

        metryka = "Linf"; %L1, L2 lub Linf
        
        %A = 0.2; %Parametr opcjonalny, domy¶lnie A = 0.5, nie zmienia skalowania
        A = [0.1;
    0.5;
    0.3;
    0.8;
    0.1;
    0.5;
    0.3;
    0.8;
    0.1;
    0.5;
    0.3;
    0.8;
    0.1;
    0.5;
    ];
        B; % = 0.8; %Parametr opcjonalny, domy¶lnie B = 1-A
        A0;
        A1;
        Fu;
    end
    
    methods (Access = private)
        
        function scoring_vector = SP_CS(app, A0, A1, Fu)
    %normalizacja danych wejściowych
    [A1_norm, A0_norm, Fu_norm]=normalize_matrix(app, A1, A0, Fu);

    %inicjalizacja wektora wyjściowego/rankingu
    scoring_vector(1:size(Fu_norm,1))=-1; 

    %wyznaczenie wektora wyjściowego/rankingu

    for i = 1:size(A1, 1)
        for j = 1:size(A1,1)
            w_points = sketch_line(app,A0_norm(i,:),A1_norm(j,:));
            for k = 1:size(Fu_norm, 1)
                if scoring_function(app,w_points, Fu_norm(k,:)) < scoring_vector(k)
                    scoring_vector(k)=scoring_function(app,w_points, Fu_norm(k,:));
                elseif scoring_vector(k)==-1
                    scoring_vector(k)=scoring_function(app,w_points, Fu_norm(k,:));
                end
            end
            plot_w_line(app,w_points);
        end
    end

    %unormowanie wektora wyjściowego/rankingu dla porównania z innymi algorytmami
    scoring_vector(2,1:size(Fu_norm,1))=(1:1:size(Fu_norm,1)); 
    scoring_vector = transpose(sortrows(transpose(scoring_vector),1));
    scoring_vector = scoring_vector(2,1:size(Fu_norm,1));
    for i=1:size(scoring_vector,2)
       k=scoring_vector(i);
       temp_scoring_vector(k)=i; 
    end
    scoring_vector=temp_scoring_vector;

    %plot punktów oraz zbiorów 
    plot_sets(app,A1_norm,A0_norm);
    plot_points(app,Fu_norm);
    %title('Data output');
        end
        
        function s = scoring_function(app, w_points, A)
    %funkcja zwraca wartość f. skoringowej dla krzywej określonej przez 
    %w_points oraz dla pkt. A
    for i = 2:size(w_points, 1)
        [temp,np]=point_to_line(app,w_points(i-1,:),w_points(i,:),A);
        d(1,i-1)=temp;
        d(2:size(np,2)+1,i-1)=transpose(np);
        scoring(i-1)=d(1,i-1)+m_line_length_to_point(app,w_points, d(2:size(np,2)+1,i-1));
    end
    s=min(scoring);
        end
        
        function p = nearest_point(~, v1,v2,pt)
            %funkcja przyjmuje dwa punkty v1 i v2 tworzące prostą, oraz punkt pt
    %zwraca punkt np który leży na prostej i jest najbliżej punktu pt
    n=v2-v1;
    temp=n;
    n=n/norm(temp);
    p=pt+((v1-pt)-(dot((v1-pt),n).*n));
        end
        
        function [distance, np] =point_to_line(app, v1,v2,pt)
            %funkcja przyjmuje dwa punkty v1 i v2 tworzące prostą, oraz punkt pt
    %zwraca odległość distance pomiędzy punktem pt a prostą oraz punkt np
    %do którego ta odległość jest liczona
    n=v2-v1;
    temp=n;
    n=n/norm(temp);
    flag_u=0;
    flag_l=0;
    check_point = nearest_point(app,v1,v2,pt);
    for i = 1:size(check_point,2)
        if check_point(i)>v2(i)
            flag_u=1;
        elseif check_point(i)<v1(i)
            flag_l=1;
        end
    end
    if flag_u==1
        if size(pt,2)==2
            distance = sqrt((v2(1)-pt(1))^2+(v2(2)-pt(2))^2);
            np = v2;
        elseif size(pt,2)==3
            distance = sqrt((v2(1)-pt(1))^2+(v2(2)-pt(2))^2+(v2(3)-pt(3))^2);
            np = v2;
        else
            distance=-1;
            np=-1;
        end
    elseif flag_l==1
        if size(pt,2)==2
            distance = sqrt((v1(1)-pt(1))^2+(v1(2)-pt(2))^2);
            np = v1;
        elseif size(pt,2)==3
            distance = sqrt((v1(1)-pt(1))^2+(v1(2)-pt(2))^2+(v1(3)-pt(3))^2);
            np = v1;
        else
            distance=-1;
            np =-1;
        end

    else
        distance=norm((v1-pt)-(dot((v1-pt),n).*n));
        np =check_point;
    end
        end
        
        function distance = m_line_length_to_point(app, w_points, A)
            %{
    zwraca znormalizowaną odległość od początku łamanej określonej przez w_
    points do punktu A który na niej leży. Jeśli podany punkt nie leży na 
    prostej, f. zwraca -1
    %}
    flag = 0;
    distance=0;
    for i = 2:size(w_points,1)
        if point_on_the_line(app,w_points(i-1,:),w_points(i,:), A) == 1
            if size(A,1) == 2
                distance=distance+sqrt((w_points(i-1,1)-A(1))^2+(w_points(i-1,2)-A(2))^2);
            end
            if size(A,1) == 3 
                distance=distance+sqrt((w_points(i-1,1)-A(1))^2+(w_points(i-1,2)-A(2))^2+(w_points(i-1,3)-A(3))^2);
            end 
            flag =1;
        else
            if flag == 0
                if size(A,1) == 2
                    distance=distance+sqrt((w_points(i,1)-w_points(i-1,1))^2+(w_points(i,2)-w_points(i-1,2))^2);
                end
                if size(A,1) == 3 
                    distance=distance+sqrt((w_points(i,1)-w_points(i-1,1))^2+(w_points(i,2)-w_points(i-1,2))^2+(w_points(i,3)-w_points(i-1,3))^2);
                end 
            end
        end
    end
    if flag == 1
        distance = distance / m_line_length(app,w_points);
    else
        distance = -1;
    end
        end
        
        function r = point_on_the_line(~, L,U,A)
            %{
    L,U - punkty tworzace odcinek w przestrzeni
    Funkcja sprawdza czy pkt. A leży na odcinku tworzonym przez te proste. 
    Jeśli tak, zwraca 1, jeśli nie, zwraca -1. 
    %}
       if size(L, 2) == 2
           l1 = sqrt((L(1)-A(1))^2+(L(2)-A(2))^2);
           l2 = sqrt((U(1)-A(1))^2+(U(2)-A(2))^2);
           l3 = sqrt((U(1)-L(1))^2+(U(2)-L(2))^2);
           l3=round(l3,4);
           l4=round(l1+l2,4);
           if l3==l4
               r=1;
           else
               r=-1;
           end
       end
       if size(L, 2) == 3
           l1 = sqrt((L(1)-A(1))^2+(L(2)-A(2))^2+(L(3)-A(3))^2);
           l2 = sqrt((U(1)-A(1))^2+(U(2)-A(2))^2+(U(3)-A(3))^2);
           l3 = sqrt((U(1)-L(1))^2+(U(2)-L(2))^2+(U(3)-L(3))^2);
           l3=round(l3,4);
           l4=round(l1+l2,4);
           if l3==l4
               r=1;
           else
               r=-1;
           end
       end
        end
        
        function line_length = m_line_length(~, w_points)
            %funkcja zwraca długość krzywej łamanej
    %pobiera punkty tworzące krzywą w postaci macierzy
    line_length = 0;
    for i = 2:size(w_points,1)
        if size(w_points,2)==2
            line_length = line_length+sqrt((w_points(i,1)-w_points(i-1,1))^2+(w_points(i,2)-w_points(i-1,2))^2);
        end
        if size(w_points, 2)==3
            line_length = line_length+sqrt((w_points(i,1)-w_points(i-1,1))^2+(w_points(i,2)-w_points(i-1,2))^2+(w_points(i,3)-w_points(i-1,3))^2);
        end
    end
        end
        
        function [A0_norm, A1_norm, Fu_norm] = normalize_matrix(~, A0, A1, Fu)
            %funkcja normalizuje zbiory 
    for i=1:size(A0,2)
       max_el = max([max(abs(A0(:,i))),max(abs(A1(:,i)))]); 
       min_el = min([min(abs(A0(:,i))),min(abs(A1(:,i)))]);
       for j=1:size(A0,1)
            if (max_el-min_el) ~= 0
                A0_norm(j,i)=(A0(j,i)-min_el)/(max_el-min_el);
            else
                A0_norm(j,i)=A0(j,i);
            end
       end 
       for j=1:size(A1,1)
            if (max_el-min_el) ~= 0
                A1_norm(j,i)=(A1(j,i)-min_el)/(max_el-min_el);
            else
                A1_norm(j,i)=A1(j,i);
            end
       end 
       for j=1:size(Fu,1)
            if (max_el-min_el) ~= 0
                Fu_norm(j,i)=(Fu(j,i)-min_el)/(max_el-min_el);
            else
                Fu_norm(j,i)=Fu(j,i);
            end
        end 
    end
        end
        
        function plot_sets(app,A0_norm, A1_norm)
    %funkcja nanosi zbiory odniesienia na plot 2D lub 3D w zależności od
    %wymiaru
    if size(A0_norm, 2) == 2
        plot(app.UIAxes2,A0_norm(:,1),A0_norm(:,2),'Color','magenta','LineStyle','--','LineWidth',1);
        hold(app.UIAxes2, 'on');
        plot(app.UIAxes2,A1_norm(:,1),A1_norm(:,2),'Color','cyan','LineStyle','--','LineWidth',1);

        hold(app.UIAxes2, 'on');
    end
    if size(A0_norm, 2) == 3
        plot3(app.UIAxes2,A0_norm(:,1),A0_norm(:,2),A0_norm(:,3),'Color','magenta','LineStyle','--','LineWidth',1);
        hold(app.UIAxes2, 'on');
        plot3(app.UIAxes2,A1_norm(:,1),A1_norm(:,2),A1_norm(:,3),'Color','cyan','LineStyle','--','LineWidth',1);
        hold(app.UIAxes2, 'on');
    end
end

function plot_points(app,Fu_norm)
    %funkcja nanosi punkty na plot 2D lub 3D w zależności od wymiaru
    if size(Fu_norm, 2) == 2
        plot(app.UIAxes2,Fu_norm(:,1), Fu_norm(:,2), 'LineStyle', 'none', 'Marker', 'o', 'MarkerEdgeColor', 'blue', 'MarkerFaceColor', 'green' );
        hold(app.UIAxes2, 'on');
    end
    if size(Fu_norm, 2) == 3
        plot3(app.UIAxes2,Fu_norm(:,1), Fu_norm(:,2),Fu_norm(:,3), 'LineStyle', 'none', 'Marker', 'o','MarkerEdgeColor', 'blue', 'MarkerFaceColor', 'green' );
        hold(app.UIAxes2, 'on');
    end
end

function plot_w_line(app,w_points)
    %funkcja sprawdza czy krzywa jest w przestrzeni 2D czy 3D i rysuje jej
    %wykres
    if size(w_points, 2) == 2
        plot(app.UIAxes2,w_points(:,1),w_points(:,2));
        hold(app.UIAxes2, 'on');
    end
    if size(w_points, 2) == 3
        plot3(app.UIAxes2,w_points(:,1),w_points(:,2),w_points(:,3));
        hold(app.UIAxes2, 'on');
    end
end
        
        function w_points = sketch_line(~, L, U)
            %funkcja zwraca macierz która składa się z punktów tworzących krzywą
    %łamaną. Przyjmuje dwa punkty w przestrzeni pomiędzy którymi ta krzywa
    %jest wyznaczana.
    if size(U,1)~=size(L,1) || size(U,2)~= size(L,2)
        w_points = -1; %sprawdzenie czy wektory są takich samych rozmiarów
    else
        L_points = L;
        U_points = U;
        for j=1:size(L,2)-1
            d=zeros(1,size(L,2));
            for k=1:size(U,2)
                d(k)=(U_points(j,k)-L_points(j,k))/2;
            end
            d_unsorted = d;
            d=sort(abs(d));
            %w_points = [x1,x2,x3 ; y1,y2,y3 ; z1,z2,z3]]
            for i = 1:size(L,2)
                if  d_unsorted(i)~= 0 
                    if d_unsorted(i)>0
                        L_points(j+1,i)=L_points(j,i)+d(j);
                        U_points(j+1,i)=U_points(j,i)-d(j);
                    else
                        L_points(j+1,i)=L_points(j,i)-d(j);
                        U_points(j+1,i)=U_points(j,i)+d(j);
                    end
                end
                if  d_unsorted(i)==0 
                    L_points(j+1,i)=L_points(j,i);
                    U_points(j+1,i)=U_points(j,i);
                end
            end
        end
        for l = 1:size(L_points,1)
            w_points(l,:)=L_points(l,:);
        end
        U_points = flipud(U_points);
        for l = 1:size(L_points,1)
            w_points(l+size(L_points,1),:)=U_points(l,:);
        end
    end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ATextArea.Value = mat2str(app.A);
            app.BTextArea.Value = mat2str(app.B);
            app.OgraniczeniaTextArea.Value = mat2str(app.ograniczenia);
            app.WektorwagowyTextArea.Value = mat2str(app.wektorWag);
            app.DanewejcioweTextArea.Value = mat2str(app.dane);
            view(app.UIAxes, 3);
            grid(app.UIAxes, "on");
            view(app.UIAxes2, 3);
            grid(app.UIAxes2, "on");
        end

        % Button pushed function: Run_TOPSISButton
        function Run_TOPSISButtonPushed(app, event)

% okreslenie wielkosci problemu
[liczbaAlternatyw, iloscKryteriow] = size(app.dane);

% sprawdzenie punktow alternatyw z ograniczeniami
alternatywyOK = zeros(liczbaAlternatyw, 1);
for i =1:liczbaAlternatyw
    for j =1:iloscKryteriow
        if app.dane(i,j) >= app.ograniczenia(1,j) && app.dane(i,j) <= app.ograniczenia(2,j)
            alternatywyOK(i,1) = i;
        else
            alternatywyOK (i,1) = 0;
            %break;
        end
    end
end

liczbaAlternatywTemp = 0;
for i =1:liczbaAlternatyw
    if alternatywyOK(i,1) == 0
    else
        liczbaAlternatywTemp = liczbaAlternatywTemp + 1;
    end
end

% uzupelnienie macierzy decyzyjnej
id = 1;
macierz_decyzyjna = zeros(liczbaAlternatywTemp,iloscKryteriow);

for i =1:liczbaAlternatyw
    if alternatywyOK(i,1) ~= 0
        for j =1:iloscKryteriow
            macierz_decyzyjna(id,j) = app.dane(i,j);
        end
        id= id+1;
    end
end

liczbaAlternatyw = liczbaAlternatywTemp;

if nargin < 7
    app.A = 0.5 * ones(liczbaAlternatyw,1);
end

if nargin < 8
    app.B = 1 - app.A;
end


% proces skalowania
macierz_skalowana = zeros(liczbaAlternatyw,iloscKryteriow);
for i =1:liczbaAlternatyw
    for j =1:iloscKryteriow
        
        if app.metryka == "L1"
            macierz_skalowana(i,j) = macierz_decyzyjna(i,j) / sum( abs(macierz_decyzyjna(:,j)) );
        elseif app.metryka == "L2"
            macierz_skalowana(i,j) = macierz_decyzyjna(i,j) / sqrt( sum((macierz_decyzyjna(:,j).^2)) );
        elseif app.metryka == "Linf"
            macierz_skalowana(i,j) = macierz_decyzyjna(i,j) / max( abs(macierz_decyzyjna(:,j)) );
        else
            error('Nieprawidowa wartosc metryka');
        end
        
        % Zmiana na max
        if( app.minMax(j) == -1 )
            macierz_skalowana(i,j) = abs( 1 - macierz_skalowana(i,j) );
        end
        
        macierz_skalowana(i,j) = macierz_skalowana(i,j) * app.wektorWag(j);
        
    end
end


%Okreslenie czy wektor antyidealny jest brany ze wszsytkich punktow czy z
%niezdominowanych
if app.pointTypes == "ALL"
    points = macierz_skalowana;
elseif app.pointTypes == "NZD"
    niezdominowane = find_niezdominowane(macierz_skalowana')';
    points = niezdominowane;
else
    error('Nieprawidowa wartosc pointTypes');
end

wektorIdealny = zeros( length(points(1,:)) , 1);
wektorAntyIdealny = zeros( length(points(1,:)) , 1);

for i = 1:length(wektorIdealny)
    wektorIdealny(i) = min( points(:,i));
    wektorAntyIdealny(i) = max( points(:,i));
end

% wyznaczenie odleglosci w przestrzeni euklidesowej
odleglosci = zeros(liczbaAlternatyw,2);
for i =1:liczbaAlternatyw
    
    if app.metryka == "L1"
        
        sumaIdealny  = 0;
        sumaAntyIdealny = 0;
        
        for j =1:iloscKryteriow
            diffIdealny = abs(macierz_skalowana(i,j) - wektorIdealny(j));
            diffAntyidealny = abs(macierz_skalowana(i,j) - wektorAntyIdealny(j));
            
            sumaIdealny = sumaIdealny + diffIdealny;
            sumaAntyIdealny = sumaAntyIdealny + diffAntyidealny;
        end
        
        % skalowanie poprzez A i B
        odleglosci(i,1) = sumaIdealny;
        odleglosci(i,2) = sumaAntyIdealny;
        
    elseif app.metryka == "L2"
        
        sumaIdealny  = 0;
        sumaAntyIdealny = 0;
        
        for j =1:iloscKryteriow
            diffIdealny = (macierz_skalowana(i,j) - wektorIdealny(j)).^2;
            diffAntyidealny = (macierz_skalowana(i,j) - wektorAntyIdealny(j)).^2;
            
            sumaIdealny = sumaIdealny + diffIdealny;
            sumaAntyIdealny = sumaAntyIdealny + diffAntyidealny;
        end
        
        % skalowanie poprzez A i B
        odleglosci(i,1) = sqrt(sumaIdealny);
        odleglosci(i,2) = sqrt(sumaAntyIdealny);
        
    elseif app.metryka == "Linf"
        
        curDiffIdealny = 0;
        curDiffAntyIdealny = 0;
        
        for j =1:iloscKryteriow
            diffIdealny = abs(macierz_skalowana(i,j) - wektorIdealny(j));
            diffAntyidealny = abs(macierz_skalowana(i,j) - wektorAntyIdealny(j));
            
            if diffIdealny > curDiffIdealny
                curDiffIdealny = diffIdealny;
            end
            
            if diffAntyidealny > curDiffAntyIdealny
                curDiffAntyIdealny = diffAntyidealny;
            end
            
        end
        
        % skalowanie poprzez A i B
        odleglosci(i,1) = curDiffIdealny;
        odleglosci(i,2) = curDiffAntyIdealny;
        
    else
        error('Nieprawidowa wartosc metryka');
    end
    
    odleglosci(i,1) = app.A(i) * odleglosci(i,1);
    odleglosci(i,2) = app.B(i) * odleglosci(i,2);
end

% obliczenie wartosci rankingu
ranking_wart = zeros(liczbaAlternatyw,1);
for i =1:liczbaAlternatyw
    if odleglosci(i,1)+odleglosci(i,2) == 0
        ranking_wart(i) = 0;
    else
        ranking_wart(i) = odleglosci(i,2)/(odleglosci(i,1)+odleglosci(i,2));
    end
end

ranking_wart_tmp = ranking_wart;

% przypisanie "miejsc" rankingu
ranking = zeros(liczbaAlternatyw,1);
last_max = -Inf;
last_place = 1;
for i = 1:length(ranking_wart_tmp)
    [v,idx] = max(ranking_wart_tmp);
    
    if abs(v - last_max) < 1e-4
        ranking(idx) = last_place;
    else
        ranking(idx) = i;
        last_place = i;
    end
    
    ranking_wart_tmp(idx) = -Inf;
    
    last_max = v;
end

ranking = ranking';
app.RankingTextArea.Value = mat2str(ranking);


hold(app.UIAxes, "on");
view(app.UIAxes, 3);
h1 = plot(app.UIAxes,macierz_skalowana(:,1),macierz_skalowana(:,2),'m*');
for i = 1:liczbaAlternatyw
    label = sprintf("Obiekt %d\nPozycja %d\nScoring: %.4f", i, ranking(i), ranking_wart(i));
    text(app.UIAxes,macierz_skalowana(i,1),macierz_skalowana(i,2),label,'HorizontalAlignment','left');
end
h2 = plot(app.UIAxes,wektorIdealny(1),wektorIdealny(2),'b*');
h3 = plot(app.UIAxes,wektorAntyIdealny(1),wektorAntyIdealny(2),'r*');
text(app.UIAxes,wektorIdealny(1), wektorIdealny(2),'Punkt Idealny','HorizontalAlignment','left');
legend(app.UIAxes,[h1 h2 h3], 'Macierz decyzyjna', 'Punkt idealny', 'Punkt antyidealny', 'Location', 'northwest');
hold(app.UIAxes, "off");
        end

        % Button pushed function: WprowadzwektorwagowyButton
        function WprowadzwektorwagowyButtonPushed(app, event)
            prompt = {'Enter rows', 'Enter columns'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0', '0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            for row = 1:str2double(cell2mat(answer(1)))
                for col = 1:str2double(cell2mat(answer(2)))
                    prompt = {['p(',num2str(row),',',num2str(col),')']};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'0'};
                    answer1 = inputdlg(prompt,dlgtitle,dims,definput);
                    wektor_wag(row,col) = str2double(cell2mat(answer1(1)));
                end
            end
            app.wektorWag = wektor_wag;
            app.WektorwagowyTextArea.Value = mat2str(app.wektorWag);
        end

        % Button pushed function: WprowadzograniczeniaButton
        function WprowadzograniczeniaButtonPushed(app, event)
            prompt = {'Enter rows', 'Enter columns'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0', '0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            for row = 1:str2double(cell2mat(answer(1)))
                for col = 1:str2double(cell2mat(answer(2)))
                    prompt = {['p(',num2str(row),',',num2str(col),')']};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'0'};
                    answer1 = inputdlg(prompt,dlgtitle,dims,definput);
                    ograniczenia_button(row,col) = str2double(cell2mat(answer1(1)));
                end
            end
            app.ograniczenia = ograniczenia_button;
            app.OgraniczeniaTextArea.Value = mat2str(app.ograniczenia);
        end

        % Value changed function: Point_typeDropDown
        function Point_typeDropDownValueChanged(app, event)
            point_type_button = app.Point_typeDropDown.Value;
            app.pointTypes = point_type_button;
        end

        % Button pushed function: WprowadzAButton
        function WprowadzAButtonPushed(app, event)
            prompt = {'Enter value of A'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.A = str2double(cell2mat(answer(1)));
            app.ATextArea.Value = mat2str(app.A);
        end

        % Button pushed function: WprowadzBButton
        function WprowadzBButtonPushed(app, event)
            prompt = {'Enter value of B'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.B = str2double(cell2mat(answer(1)));
            app.BTextArea.Value = mat2str(app.B);
        end

        % Button pushed function: DanezplikuButton
        function DanezplikuButtonPushed(app, event)
            [file,folder] = uigetfile({'*'});
            filename = fullfile(folder,file);
            dataTable = readtable(filename);
            dataButton = table2array(dataTable);
            app.DanewejcioweTextArea.Value = mat2str(dataButton);
            app.dane = dataButton;
        end

        % Value changed function: MinMaxDropDown
        function MinMaxDropDownValueChanged(app, event)
            value = app.MinMaxDropDown.Value;
            if value == "MIN"
                tmp = ones(size(app.wektorWag));
                minMaxButton = tmp;
                app.minMax = minMaxButton;
            end
            if value == "MAX"
                tmp = ones(size(app.wektorWag));
                minMaxButton = -1*tmp;
                app.minMax = minMaxButton;
            end
        end

        % Button pushed function: Run_SP_CSButton
        function Run_SP_CSButtonPushed(app, event)
            scoring_vector=SP_CS(app, app.A0,app.A1,app.Fu);
            app.Ranking_2TextArea.Value=num2str(scoring_vector);
        end

        % Button pushed function: ImportujA0Button
        function ImportujA0ButtonPushed(app, event)
            [file,folder] = uigetfile({'*'});
            filename = fullfile(folder,file);
            dataTable = readtable(filename);
            dataButton = table2array(dataTable);
            app.A0TextArea.Value = mat2str(dataButton);
            app.A0 = dataButton;
        end

        % Button pushed function: ImportujA1Button
        function ImportujA1ButtonPushed(app, event)
            [file,folder] = uigetfile({'*'});
            filename = fullfile(folder,file);
            dataTable = readtable(filename);
            dataButton = table2array(dataTable);
            app.A1TextArea.Value = mat2str(dataButton);
            app.A1 = dataButton;
        end

        % Button pushed function: ImportujFuButton
        function ImportujFuButtonPushed(app, event)
            [file,folder] = uigetfile({'*'});
            filename = fullfile(folder,file);
            dataTable = readtable(filename);
            dataButton = table2array(dataTable);
            app.FuTextArea.Value = mat2str(dataButton);
            app.Fu = dataButton;
        end

        % Value changed function: MetrykaDropDown
        function MetrykaDropDownValueChanged(app, event)
            value = app.MetrykaDropDown.Value;
            if value == "Linf"
                app.metryka = "Linf";
            end
            if value == "L1"
                app.metryka = "L1";
            end
            if value == "L2"
                app.metryka = "L2";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TOPSISTab = uitab(app.TabGroup);
            app.TOPSISTab.Title = 'TOPSIS';
            app.RankingTextAreaLabel = uilabel(app.TOPSISTab);
            app.RankingTextAreaLabel.Text = 'Ranking';
            app.RankingTextArea = uitextarea(app.TOPSISTab);
            app.Run_TOPSISButton = uibutton(app.TOPSISTab, 'push');
            app.Run_TOPSISButton.ButtonPushedFcn = createCallbackFcn(app, @Run_TOPSISButtonPushed, true);
            app.Run_TOPSISButton.Text = 'Run_TOPSIS';
            app.WprowadzwektorwagowyButton = uibutton(app.TOPSISTab, 'push');
            app.WprowadzwektorwagowyButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzwektorwagowyButtonPushed, true);
            app.WprowadzwektorwagowyButton.Text = 'Wprowadz wektor wagowy';
            app.WprowadzograniczeniaButton = uibutton(app.TOPSISTab, 'push');
            app.WprowadzograniczeniaButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzograniczeniaButtonPushed, true);
            app.WprowadzograniczeniaButton.Text = 'Wprowadz ograniczenia';
            app.WprowadzAButton = uibutton(app.TOPSISTab, 'push');
            app.WprowadzAButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzAButtonPushed, true);
            app.WprowadzAButton.Text = 'Wprowadz A';
            app.WprowadzBButton = uibutton(app.TOPSISTab, 'push');
            app.WprowadzBButton.ButtonPushedFcn = createCallbackFcn(app, @WprowadzBButtonPushed, true);
            app.WprowadzBButton.Text = 'Wprowadz B';
            app.UIAxes = uiaxes(app.TOPSISTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DanezplikuButton = uibutton(app.TOPSISTab, 'push');
            app.DanezplikuButton.ButtonPushedFcn = createCallbackFcn(app, @DanezplikuButtonPushed, true);
            app.DanezplikuButton.Text = 'Dane z pliku';
            app.Point_typeDropDownLabel = uilabel(app.TOPSISTab);
            app.Point_typeDropDownLabel.Text = 'Point_type';
            app.Point_typeDropDown = uidropdown(app.TOPSISTab);
            app.Point_typeDropDown.Items = {'ALL', 'NZD'};
            app.Point_typeDropDown.ValueChangedFcn = createCallbackFcn(app, @Point_typeDropDownValueChanged, true);
            app.Point_typeDropDown.Value = 'ALL';
            app.WektorwagowyTextAreaLabel = uilabel(app.TOPSISTab);
            app.WektorwagowyTextAreaLabel.Text = 'Wektor wagowy';
            app.WektorwagowyTextArea = uitextarea(app.TOPSISTab);
            app.OgraniczeniaTextAreaLabel = uilabel(app.TOPSISTab);
            app.OgraniczeniaTextAreaLabel.Text = 'Ograniczenia';
            app.OgraniczeniaTextArea = uitextarea(app.TOPSISTab);
            app.ATextAreaLabel = uilabel(app.TOPSISTab);
            app.ATextAreaLabel.Text = 'A';
            app.ATextArea = uitextarea(app.TOPSISTab);
            app.BTextAreaLabel = uilabel(app.TOPSISTab);
            app.BTextAreaLabel.Text = 'B';
            app.BTextArea = uitextarea(app.TOPSISTab);
            app.DanewejcioweTextAreaLabel = uilabel(app.TOPSISTab);
            app.DanewejcioweTextAreaLabel.Text = 'Dane wejściowe';
            app.DanewejcioweTextArea = uitextarea(app.TOPSISTab);
            app.MinMaxDropDownLabel = uilabel(app.TOPSISTab);
            app.MinMaxDropDownLabel.Text = 'MinMax';
            app.MinMaxDropDown = uidropdown(app.TOPSISTab);
            app.MinMaxDropDown.Items = {'MAX', 'MIN'};
            app.MinMaxDropDown.ValueChangedFcn = createCallbackFcn(app, @MinMaxDropDownValueChanged, true);
            app.MinMaxDropDown.Value = 'MAX';
            app.MetrykaDropDownLabel = uilabel(app.TOPSISTab);
            app.MetrykaDropDownLabel.Text = 'Metryka';
            app.MetrykaDropDown = uidropdown(app.TOPSISTab);
            app.MetrykaDropDown.Items = {'Linf', 'L1', 'L2'};
            app.MetrykaDropDown.ValueChangedFcn = createCallbackFcn(app, @MetrykaDropDownValueChanged, true);
            app.MetrykaDropDown.Value = 'Linf';
            app.SP_CSTab = uitab(app.TabGroup);
            app.SP_CSTab.Title = 'SP_CS';
            app.Run_SP_CSButton = uibutton(app.SP_CSTab, 'push');
            app.Run_SP_CSButton.ButtonPushedFcn = createCallbackFcn(app, @Run_SP_CSButtonPushed, true);
            app.Run_SP_CSButton.Text = 'Run_SP_CS';
            app.Ranking_2TextAreaLabel = uilabel(app.SP_CSTab);
            app.Ranking_2TextAreaLabel.Text = 'Ranking_2';
            app.Ranking_2TextArea = uitextarea(app.SP_CSTab);
            app.ImportujA0Button = uibutton(app.SP_CSTab, 'push');
            app.ImportujA0Button.ButtonPushedFcn = createCallbackFcn(app, @ImportujA0ButtonPushed, true);
            app.ImportujA0Button.Text = 'Importuj A0';
            app.A0TextAreaLabel = uilabel(app.SP_CSTab);
            app.A0TextAreaLabel.Text = 'A0';
            app.A0TextArea = uitextarea(app.SP_CSTab);
            app.ImportujA1Button = uibutton(app.SP_CSTab, 'push');
            app.ImportujA1Button.ButtonPushedFcn = createCallbackFcn(app, @ImportujA1ButtonPushed, true);
            app.ImportujA1Button.Text = 'Importuj A1';
            app.A1TextAreaLabel = uilabel(app.SP_CSTab);
            app.A1TextAreaLabel.Text = 'A1';
            app.A1TextArea = uitextarea(app.SP_CSTab);
            app.ImportujFuButton = uibutton(app.SP_CSTab, 'push');
            app.ImportujFuButton.ButtonPushedFcn = createCallbackFcn(app, @ImportujFuButtonPushed, true);
            app.ImportujFuButton.Text = 'Importuj Fu';
            app.FuTextAreaLabel = uilabel(app.SP_CSTab);
            app.FuTextAreaLabel.Text = 'Fu';
            app.FuTextArea = uitextarea(app.SP_CSTab);
            app.UIAxes2 = uiaxes(app.SP_CSTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SprawdzaniewarunkwniesprzecznociTab = uitab(app.TabGroup);
            app.SprawdzaniewarunkwniesprzecznociTab.Title = 'Sprawdzanie warunków niesprzeczności';
            app.ImportujA0Button_2 = uibutton(app.SprawdzaniewarunkwniesprzecznociTab, 'push');
            app.ImportujA0Button_2.Text = 'Importuj A0';
            app.A0TextArea_2Label = uilabel(app.SprawdzaniewarunkwniesprzecznociTab);
            app.A0TextArea_2Label.Text = 'A0';
            app.A0TextArea_2 = uitextarea(app.SprawdzaniewarunkwniesprzecznociTab);
            app.ImportujA1Button_2 = uibutton(app.SprawdzaniewarunkwniesprzecznociTab, 'push');
            app.ImportujA1Button_2.Text = 'Importuj A1';
            app.A1TextArea_2Label = uilabel(app.SprawdzaniewarunkwniesprzecznociTab);
            app.A1TextArea_2Label.Text = 'A1';
            app.A1TextArea_2 = uitextarea(app.SprawdzaniewarunkwniesprzecznociTab);
            app.ImportujA2Button = uibutton(app.SprawdzaniewarunkwniesprzecznociTab, 'push');
            app.ImportujA2Button.Text = 'Importuj A2';
            app.ImportujA3Button = uibutton(app.SprawdzaniewarunkwniesprzecznociTab, 'push');
            app.ImportujA3Button.Text = 'Importuj A3';
            app.A2TextAreaLabel = uilabel(app.SprawdzaniewarunkwniesprzecznociTab);
            app.A2TextAreaLabel.Text = 'A2';
            app.A2TextArea = uitextarea(app.SprawdzaniewarunkwniesprzecznociTab);
            app.A3TextAreaLabel = uilabel(app.SprawdzaniewarunkwniesprzecznociTab);
            app.A3TextAreaLabel.Text = 'A3';
            app.A3TextArea = uitextarea(app.SprawdzaniewarunkwniesprzecznociTab);
            app.UIAxes3 = uiaxes(app.SprawdzaniewarunkwniesprzecznociTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.RunButton = uibutton(app.SprawdzaniewarunkwniesprzecznociTab, 'push');
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_ow_lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end